classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CoprightWatermarkSystemLabel  matlab.ui.control.Label
        AddWatermarkButton            matlab.ui.control.Button
        RemoveWatremarkButton         matlab.ui.control.Button
        DetectWatermarkButton         matlab.ui.control.Button
        InsertWatermarkButton         matlab.ui.control.Button
        InsertOriginalButton          matlab.ui.control.Button
        UIAxes5                       matlab.ui.control.UIAxes
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        originalImage,watermarkImage,embeddedImage,watermarkedImage
        Property % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: InsertOriginalButton, UIAxes
        function InsertOriginalButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files'}, 'Select Original Image');
            if filename
                app.originalImage = imread(fullfile(pathname, filename));
                imshow(app.originalImage, 'Parent', app.UIAxes);
                title(app.UIAxes, 'Original Image');
            end
        end

        % Button pushed function: InsertWatermarkButton
        function InsertWatermarkButtonPushed(app, event)
                        [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files'}, 'Select Original Image');
            if filename
                app.watermarkImage = imread(fullfile(pathname, filename));
                imshow(app.watermarkImage, 'Parent', app.UIAxes2);
                title(app.UIAxes2, 'Watermark');
            end
        end

        % Button pushed function: DetectWatermarkButton
        function DetectWatermarkButtonPushed(app, event)
            if ~isempty(app.embeddedImage)
                % Call the detect_watermark function to extract the watermark in RGB
                extractedWatermark = detect_watermark(app.embeddedImage);
                
                % Display the extracted watermark in RGB format
                imshow(extractedWatermark, [], 'Parent', app.UIAxes5);
                title(app.UIAxes5, 'Extracted Watermark');
            else
                % Show an error message if no watermarked image is loaded
                msgbox('Please insert the watermarked image first.', 'Error', 'error');
            end

        end

        % Button pushed function: AddWatermarkButton
        function AddWatermarkButtonPushed(app, event)
               % Ensure the original image is in RGB
    if size(app.originalImage, 3) == 3
        originalImageRGB = app.originalImage;  % The original image is already RGB
    else
        originalImageRGB = repmat(app.originalImage, [1, 1, 3]);  % Convert grayscale to RGB
    end

    % Ensure the watermark is in RGB (either already RGB or convert grayscale to RGB)
    if size(app.watermarkImage, 3) == 3
        watermarkImageRGB = app.watermarkImage;  % The watermark is already RGB
    else
        watermarkImageRGB = repmat(app.watermarkImage, [1, 1, 3]);  % Convert grayscale watermark to RGB
    end

    % Resize watermark to match the original image size
    watermarkResized = imresize(watermarkImageRGB, size(originalImageRGB, [1, 2]));

    % Convert the resized watermark to binary (grayscale conversion before binarization)
    watermarkBinary = imbinarize(rgb2gray(watermarkResized));

    % Create a new image for embedding watermark on each channel (R, G, B)
    app.embeddedImage = originalImageRGB;  % Start with the original RGB image
    for channel = 1:3
        app.embeddedImage(:,:,channel) = bitset(originalImageRGB(:,:,channel), 1, watermarkBinary);  % Embed watermark in each channel
    end

    % Save the watermarked image in RGB format
    imwrite(app.embeddedImage, 'watermarked_image.jpg');
    
    % Optionally, display the watermarked image
    imshow(app.embeddedImage, 'Parent', app.UIAxes3);  % Display the watermarked image in RGB format
    title(app.UIAxes3, 'Watermarked Image');  % Title for the display
            
        end

        % Button pushed function: RemoveWatremarkButton
        function RemoveWatremarkButtonPushed(app, event)
                % Make sure both images are RGB
            if size(app.originalImage, 3) == 3
                originalImageRGB = app.originalImage;
            else
                originalImageRGB = repmat(app.originalImage, [1, 1, 3]);
            end
            
            % Convert the watermark to grayscale and resize it
            if size(app.watermarkImage, 3) == 3
                watermarkImageGray = rgb2gray(app.watermarkImage);
            else
                watermarkImageGray = app.watermarkImage;
            end
            watermarkResized = imresize(watermarkImageGray, size(originalImageRGB, [1, 2]));
            watermarkBinary = imbinarize(watermarkResized);
            
            % Create a new image for embedding watermark on each channel (R, G, B)
            app.embeddedImage = originalImageRGB; 
            for channel = 1:3
                app.embeddedImage(:,:,channel) = bitset(originalImageRGB(:,:,channel), 1, watermarkBinary);
            end
            
            % Save the watermarked image
            imwrite(app.embeddedImage, 'watermarked_image.jpg');
            if ~isempty(app.embeddedImage)
            restoredImage = remove_watermark(app.embeddedImage);
            imshow(restoredImage, [], 'Parent', app.UIAxes4);
            title(app.UIAxes4, 'Restore Orginal Image');
            else
                msgbox('Please insert the watermarked image first.', 'Error', 'error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @InsertOriginalButtonPushed, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Watermark ')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Watermarked Image')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Restored Orginal Image')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Extracted Watermark')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.InsertOriginalButton = uibutton(app.UIFigure, 'push');
            app.InsertOriginalButton.ButtonPushedFcn = createCallbackFcn(app, @InsertOriginalButtonPushed, true);
            app.InsertOriginalButton.Text = 'Insert Original ';
            app.InsertWatermarkButton = uibutton(app.UIFigure, 'push');
            app.InsertWatermarkButton.ButtonPushedFcn = createCallbackFcn(app, @InsertWatermarkButtonPushed, true);
            app.InsertWatermarkButton.Text = 'Insert Watermark';
            app.DetectWatermarkButton = uibutton(app.UIFigure, 'push');
            app.DetectWatermarkButton.ButtonPushedFcn = createCallbackFcn(app, @DetectWatermarkButtonPushed, true);
            app.DetectWatermarkButton.Text = 'Detect Watermark';
            app.RemoveWatremarkButton = uibutton(app.UIFigure, 'push');
            app.RemoveWatremarkButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveWatremarkButtonPushed, true);
            app.RemoveWatremarkButton.Text = 'Remove Watremark';
            app.AddWatermarkButton = uibutton(app.UIFigure, 'push');
            app.AddWatermarkButton.ButtonPushedFcn = createCallbackFcn(app, @AddWatermarkButtonPushed, true);
            app.AddWatermarkButton.Text = 'Add Watermark';
            app.CoprightWatermarkSystemLabel = uilabel(app.UIFigure);
            app.CoprightWatermarkSystemLabel.Text = 'Copright Watermark System';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end